classdef FitnessTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        FrontPageTab                  matlab.ui.container.Tab
        GetStartedButton              matlab.ui.control.Button
        CreatedbyMathWorksInternshipGroup17Label  matlab.ui.control.Label
        FitnessTrackerLabel_3         matlab.ui.control.Label
        DailyCalorieEstimatorTab      matlab.ui.container.Tab
        WorkoutSummaryButton          matlab.ui.control.Button
        HomeButton                    matlab.ui.control.Button
        DailyCalorieButton            matlab.ui.control.Button
        FoodTrackerButton             matlab.ui.control.Button
        FitnessTrackerLabel_2         matlab.ui.control.Label
        TotalCaloriesBurnedEditField  matlab.ui.control.NumericEditField
        ActivityLevelDropDown         matlab.ui.control.DropDown
        ActivityLevelDropDownLabel    matlab.ui.control.Label
        GenderDropDown                matlab.ui.control.DropDown
        GenderDropDownLabel           matlab.ui.control.Label
        AgeyearsEditField             matlab.ui.control.NumericEditField
        AgeyearsEditFieldLabel        matlab.ui.control.Label
        HeightcmEditField             matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel        matlab.ui.control.Label
        WeightkgEditField             matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel        matlab.ui.control.Label
        TotalCaloriesBurnedEditFieldLabel  matlab.ui.control.Label
        CalculateButton               matlab.ui.control.StateButton
        PleaseenterthefollowinginformationLabel  matlab.ui.control.Label
        FoodTrackerTab                matlab.ui.container.Tab
        Panel_2                       matlab.ui.container.Panel
        ResetButton                   matlab.ui.control.Button
        RemoveFoodButton              matlab.ui.control.Button
        AddFoodButton                 matlab.ui.control.Button
        FoodsEatenTodayListBox        matlab.ui.control.ListBox
        FoodsEatenTodayListBoxLabel   matlab.ui.control.Label
        MaintenanceCheckBox           matlab.ui.control.CheckBox
        DeficitCheckBox_2             matlab.ui.control.CheckBox
        SurplusCheckBox               matlab.ui.control.CheckBox
        ServingSizeEditField          matlab.ui.control.NumericEditField
        ServingSizeEditFieldLabel     matlab.ui.control.Label
        TextArea_3                    matlab.ui.control.TextArea
        TextArea_3Label               matlab.ui.control.Label
        TextArea_2                    matlab.ui.control.TextArea
        TextArea_2Label               matlab.ui.control.Label
        TextAreaLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        ProteinEditField              matlab.ui.control.NumericEditField
        ProteinEditFieldLabel         matlab.ui.control.Label
        NameofFoodEditField           matlab.ui.control.EditField
        NameofFoodEditFieldLabel      matlab.ui.control.Label
        TotalCaloriesEditField        matlab.ui.control.NumericEditField
        TotalCaloriesEditFieldLabel   matlab.ui.control.Label
        CaloriesofFoodEditField       matlab.ui.control.NumericEditField
        CaloriesofFoodEditFieldLabel  matlab.ui.control.Label
        FatEditField                  matlab.ui.control.NumericEditField
        FatEditFieldLabel             matlab.ui.control.Label
        CarbohydratesEditField        matlab.ui.control.NumericEditField
        CarbohydratesEditFieldLabel   matlab.ui.control.Label
        CalculateCaloriesButton       matlab.ui.control.Button
        PleaseenterthefollowinginformationLabel_2  matlab.ui.control.Label
        FoodTrackerButton_4           matlab.ui.control.Button
        DailyCalorieButton_4          matlab.ui.control.Button
        HomeButton_4                  matlab.ui.control.Button
        WorkoutSummaryButton_4        matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        WorkoutSummaryTab             matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        WorkoutSummaryLabel           matlab.ui.control.Label
        WorkoutSummaryButton_3        matlab.ui.control.Button
        HomeButton_3                  matlab.ui.control.Button
        DailyCalorieButton_3          matlab.ui.control.Button
        FoodTrackerButton_3           matlab.ui.control.Button
        SelectWorkoutButton           matlab.ui.control.Button
        ActivityBreakdownGraph        matlab.ui.control.UIAxes
        EffortGraph                   matlab.ui.control.UIAxes
        TotalStepsGraph               matlab.ui.control.UIAxes
        Tab                           matlab.ui.container.Tab
        FilenameEditField             matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
        ImportDataButton              matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        totalProtein = 0;
        totalCarbs = 0;
        totalFat = 0;
        totalCalories = 0;
        workoutQuery = []; %Store actual workouts 
        workoutLabels = []; %Store name of workouts
        workoutSteps = []; %Store array for number of steps for each workout
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("HelperFunctions\");
            addpath("Data\");
            addpath("ActivityFunctions\");
        end

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            %Assign variables and store them into struct
            userData.weight = app.WeightkgEditField.Value;
            userData.height = app.HeightcmEditField.Value;
            userData.age = app.AgeyearsEditField.Value;
            userData.gender = app.GenderDropDown.Value;
            userData.activityLvl = app.ActivityLevelDropDown.Value;
            userData.totalCaloriesEaten = 0;

            %Call function to calculate total calories burned
            app.TotalCaloriesBurnedEditField.Value = calculateTDEE(userData);

            %Save value to struct
            userData.TDEE = app.TotalCaloriesBurnedEditField.Value;

            %Calculate Caloric Deficit and Surplus 
            minimumCal = userData.TDEE * 0.10; %minimum threshold to be in surplus/deficit
            userData.caloricDeficit = userData.TDEE - minimumCal;
            userData.caloricSurplus = userData.TDEE + minimumCal;
            
            %Update the plot for Recommended Caloric Graph
            labels = categorical({'Deficit','Maintanence','Surplus','Current'});
            labels = reordercats(labels,{'Deficit','Maintanence','Surplus','Current'});
            caloricStates = [userData.caloricDeficit userData.TDEE userData.caloricSurplus 1]; 
            bar(app.UIAxes2, labels, caloricStates);
            title(app.UIAxes2, 'Caloric Graph');

            %Save struct as file to use later
            save('userData.mat', 'userData');
        end

        % Button pushed function: CalculateCaloriesButton
        function CalculateButton_2Pushed(app, event)
            load("userData.mat","userData")
            %Assign variables and store them in food struct
            foods = getNutritionData(app.NameofFoodEditField.Value);
            app.ProteinEditField.Value = foods.foods(1).nf_protein;
            app.FatEditField.Value = foods.foods(1).nf_total_fat;
            app.CarbohydratesEditField.Value = foods.foods(1).nf_total_carbohydrate;
            app.ServingSizeEditField.Value = foods.foods(1).serving_weight_grams;
            
            foodData.name = app.NameofFoodEditField.Value;
            foodData.protein = app.ProteinEditField.Value;
            foodData.fat = app.FatEditField.Value;
            foodData.carbohydrates = app.CarbohydratesEditField.Value;
            foodData.servingGrams = app.ServingSizeEditField.Value;
            
            %Update total calories eaten
            userData.totalCaloriesEaten = userData.totalCaloriesEaten + calcMacros(foodData);

            %Calculate calories of food and total calories for person
            app.CaloriesofFoodEditField.Value = calcMacros(foodData);
            app.TotalCaloriesEditField.Value = userData.totalCaloriesEaten;
            %app.Slider.Value = userData.totalCaloriesEaten; removed it
            
            %% Update Macronutrients Graph
            % Add current food's macros to cumulative totals
            app.totalProtein = app.totalProtein + foodData.protein;
            app.totalCarbs   = app.totalCarbs   + foodData.carbohydrates;
            app.totalFat     = app.totalFat     + foodData.fat;
            
            % Create matrix of cumulative macros
            macroGrams = [app.totalProtein, app.totalCarbs, app.totalFat];
            
            % Update pie chart
            cla(app.UIAxes);
            pie(app.UIAxes, macroGrams);
            title(app.UIAxes, 'Actual Macronutrients');
            
            %% Update Caloric Graph
            labels = categorical({'Deficit','Maintanence','Surplus','Current'});
            labels = reordercats(labels,{'Deficit','Maintanence','Surplus','Current'});

            cla(app.UIAxes2);
            caloricStates = [userData.caloricDeficit userData.TDEE userData.caloricSurplus userData.totalCaloriesEaten];
            bar(app.UIAxes2, labels, caloricStates)
            title(app.UIAxes2, 'Caloric Graph');

            %% Save foods and total calories for the day
            save("foodData.mat", "foodData", "-append");
            save("userData.mat","userData");
        end

        % Button pushed function: DailyCalorieButton, 
        % ...and 3 other components
        function GetStartedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DailyCalorieEstimatorTab;
        end

        % Button pushed function: FoodTrackerButton, FoodTrackerButton_3, 
        % ...and 1 other component
        function FoodTrackerButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FoodTrackerTab;
        end

        % Button pushed function: HomeButton, HomeButton_3, HomeButton_4
        function HomeButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FrontPageTab;
        end

        % Button pushed function: WorkoutSummaryButton, 
        % ...and 2 other components
        function WorkoutSummaryButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.WorkoutSummaryTab;
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
        end

        % Button pushed function: SelectWorkoutButton
        function SelectWorkoutButtonPushed(app, event)
            [file, path] = uigetfile({'Data/MixedActivity/*'}, 'Please select a workout file');
            fullFilePath = fullfile(path, file);
            addpath("ActivityFunctions");
            
            %Compute step for given workout and store in array
            stepsOfOneWorkout = getTotalSteps(fullFilePath); %func call
            app.workoutSteps = [app.workoutSteps, stepsOfOneWorkout]; 
            
            %Store name of workout in array
            fileName = replace(file,"_"," ");
            fileName = erase(fileName, ".mat");
            app.workoutLabels = [app.workoutLabels, categorical(cellstr(fileName))];

            % Clear graph and plot a new graph with updated workouts
            cla(app.TotalStepsGraph); %clear the graph
            barh(app.TotalStepsGraph, app.workoutLabels, app.workoutSteps);

            %% Generate a barh graph to represent the intensity level between dif workouts
            
            %% Generate activity breakdown graph
            model = load('trainedModel.mat');
            activity = load(fullFilePath); %load to get access to Acceleration
            justAcc = timetable2table(activity.Acceleration, "ConvertRowTimes", false); %remove timestamps
            yfit = model.trainedModel.predictFcn(justAcc); % train the model picked out from classficationLearner
            yfitcat = categorical(cellstr(yfit)); % convert to correct data type for histogram
            cla(app.ActivityBreakdownGraph)
            pie(app.ActivityBreakdownGraph, yfitcat);
            
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [filename,path] = uigetfile();
            app.FilenameEditField.Value = filename;
            figure(app.UIFigure);
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FrontPageTab = uitab(app.TabGroup);
            app.FrontPageTab.Title = 'Front Page';
            app.FitnessTrackerLabel_3 = uilabel(app.FrontPageTab);
            app.FitnessTrackerLabel_3.Text = 'Fitness Tracker';
            app.CreatedbyMathWorksInternshipGroup17Label = uilabel(app.FrontPageTab);
            app.CreatedbyMathWorksInternshipGroup17Label.Text = 'Created by MathWorks Internship Group 17';
            app.GetStartedButton = uibutton(app.FrontPageTab, 'push');
            app.GetStartedButton.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.GetStartedButton.Text = 'Get Started';
            app.DailyCalorieEstimatorTab = uitab(app.TabGroup);
            app.DailyCalorieEstimatorTab.Title = 'Daily Calorie Estimator';
            app.PleaseenterthefollowinginformationLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.PleaseenterthefollowinginformationLabel.Text = 'Please enter the following information';
            app.CalculateButton = uibutton(app.DailyCalorieEstimatorTab, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.TotalCaloriesBurnedEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.TotalCaloriesBurnedEditFieldLabel.Text = 'Total Calories Burned';
            app.WeightkgEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.WeightkgEditFieldLabel.Text = 'Weight (kg)';
            app.WeightkgEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.WeightkgEditField.AllowEmpty = 'on';
            app.WeightkgEditField.Value = [];
            app.HeightcmEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.HeightcmEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.HeightcmEditField.AllowEmpty = 'on';
            app.HeightcmEditField.Value = [];
            app.AgeyearsEditFieldLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.AgeyearsEditFieldLabel.Text = 'Age (years)';
            app.AgeyearsEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.AgeyearsEditField.AllowEmpty = 'on';
            app.AgeyearsEditField.Value = [];
            app.GenderDropDownLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.DailyCalorieEstimatorTab);
            app.GenderDropDown.Items = {'Male ', 'Female'};
            app.GenderDropDown.Value = 'Male ';
            app.ActivityLevelDropDownLabel = uilabel(app.DailyCalorieEstimatorTab);
            app.ActivityLevelDropDownLabel.Text = 'Activity Level';
            app.ActivityLevelDropDown = uidropdown(app.DailyCalorieEstimatorTab);
            app.ActivityLevelDropDown.Items = {'Sedentary (little to no exercise)', 'Lightly active (light exercise 1–3 days per week)', 'Moderately active (moderate exercise 3–5 days per week)', 'Very active (hard exercise 6–7 days per week)', 'Extra active (very hard exercise two or more times per day, training, or a physical job)'};
            app.ActivityLevelDropDown.Value = 'Sedentary (little to no exercise)';
            app.TotalCaloriesBurnedEditField = uieditfield(app.DailyCalorieEstimatorTab, 'numeric');
            app.TotalCaloriesBurnedEditField.Editable = 'off';
            app.FitnessTrackerLabel_2 = uilabel(app.DailyCalorieEstimatorTab);
            app.FitnessTrackerLabel_2.Text = 'Fitness Tracker';
            app.FoodTrackerButton = uibutton(app.DailyCalorieEstimatorTab, 'push');
            app.FoodTrackerButton.ButtonPushedFcn = createCallbackFcn(app, @FoodTrackerButtonPushed, true);
            app.FoodTrackerButton.Text = 'Food Tracker';
            app.DailyCalorieButton = uibutton(app.DailyCalorieEstimatorTab, 'push');
            app.DailyCalorieButton.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.DailyCalorieButton.Text = 'Daily Calorie';
            app.HomeButton = uibutton(app.DailyCalorieEstimatorTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.WorkoutSummaryButton = uibutton(app.DailyCalorieEstimatorTab, 'push');
            app.WorkoutSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @WorkoutSummaryButtonPushed, true);
            app.WorkoutSummaryButton.Text = 'Workout Summary';
            app.FoodTrackerTab = uitab(app.TabGroup);
            app.FoodTrackerTab.Title = 'Food Tracker';
            app.Panel_2 = uipanel(app.FoodTrackerTab);
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'Calorie Comparision')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Actual Macronutrients')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WorkoutSummaryButton_4 = uibutton(app.Panel_2, 'push');
            app.WorkoutSummaryButton_4.ButtonPushedFcn = createCallbackFcn(app, @WorkoutSummaryButtonPushed, true);
            app.WorkoutSummaryButton_4.Text = 'Workout Summary';
            app.HomeButton_4 = uibutton(app.Panel_2, 'push');
            app.HomeButton_4.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton_4.Text = 'Home';
            app.DailyCalorieButton_4 = uibutton(app.Panel_2, 'push');
            app.DailyCalorieButton_4.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.DailyCalorieButton_4.Text = 'Daily Calorie';
            app.FoodTrackerButton_4 = uibutton(app.Panel_2, 'push');
            app.FoodTrackerButton_4.ButtonPushedFcn = createCallbackFcn(app, @FoodTrackerButtonPushed, true);
            app.FoodTrackerButton_4.Text = 'Food Tracker';
            app.PleaseenterthefollowinginformationLabel_2 = uilabel(app.Panel_2);
            app.PleaseenterthefollowinginformationLabel_2.Text = 'Please enter the following information';
            app.CalculateCaloriesButton = uibutton(app.Panel_2, 'push');
            app.CalculateCaloriesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateCaloriesButton.Text = 'Calculate Calories';
            app.CarbohydratesEditFieldLabel = uilabel(app.Panel_2);
            app.CarbohydratesEditFieldLabel.Text = 'Carbohydrates';
            app.CarbohydratesEditField = uieditfield(app.Panel_2, 'numeric');
            app.CarbohydratesEditField.AllowEmpty = 'on';
            app.CarbohydratesEditField.Editable = 'off';
            app.CarbohydratesEditField.Value = [];
            app.FatEditFieldLabel = uilabel(app.Panel_2);
            app.FatEditFieldLabel.Text = 'Fat';
            app.FatEditField = uieditfield(app.Panel_2, 'numeric');
            app.FatEditField.AllowEmpty = 'on';
            app.FatEditField.Editable = 'off';
            app.FatEditField.Value = [];
            app.CaloriesofFoodEditFieldLabel = uilabel(app.Panel_2);
            app.CaloriesofFoodEditFieldLabel.Text = 'Calories of Food';
            app.CaloriesofFoodEditField = uieditfield(app.Panel_2, 'numeric');
            app.CaloriesofFoodEditField.AllowEmpty = 'on';
            app.CaloriesofFoodEditField.Editable = 'off';
            app.CaloriesofFoodEditField.Value = [];
            app.TotalCaloriesEditFieldLabel = uilabel(app.Panel_2);
            app.TotalCaloriesEditFieldLabel.Text = 'Total Calories';
            app.TotalCaloriesEditField = uieditfield(app.Panel_2, 'numeric');
            app.TotalCaloriesEditField.AllowEmpty = 'on';
            app.TotalCaloriesEditField.Editable = 'off';
            app.TotalCaloriesEditField.Value = [];
            app.NameofFoodEditFieldLabel = uilabel(app.Panel_2);
            app.NameofFoodEditFieldLabel.Text = 'Name of Food';
            app.NameofFoodEditField = uieditfield(app.Panel_2, 'text');
            app.ProteinEditFieldLabel = uilabel(app.Panel_2);
            app.ProteinEditFieldLabel.Text = 'Protein';
            app.ProteinEditField = uieditfield(app.Panel_2, 'numeric');
            app.ProteinEditField.AllowEmpty = 'on';
            app.ProteinEditField.Editable = 'off';
            app.ProteinEditField.Value = [];
            app.TextArea = uitextarea(app.Panel_2);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Protein'};
            app.TextAreaLabel = uilabel(app.Panel_2);
            app.TextAreaLabel.Text = '.';
            app.TextArea_2Label = uilabel(app.Panel_2);
            app.TextArea_2Label.Text = '.';
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Carbs'};
            app.TextArea_3Label = uilabel(app.Panel_2);
            app.TextArea_3Label.Text = '.';
            app.TextArea_3 = uitextarea(app.Panel_2);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Fats'};
            app.ServingSizeEditFieldLabel = uilabel(app.Panel_2);
            app.ServingSizeEditFieldLabel.Text = 'Serving Size';
            app.ServingSizeEditField = uieditfield(app.Panel_2, 'numeric');
            app.ServingSizeEditField.AllowEmpty = 'on';
            app.ServingSizeEditField.Editable = 'off';
            app.ServingSizeEditField.Value = [];
            app.SurplusCheckBox = uicheckbox(app.Panel_2);
            app.SurplusCheckBox.Text = 'Surplus';
            app.DeficitCheckBox_2 = uicheckbox(app.Panel_2);
            app.DeficitCheckBox_2.Text = 'Deficit';
            app.MaintenanceCheckBox = uicheckbox(app.Panel_2);
            app.MaintenanceCheckBox.Text = 'Maintenance';
            app.FoodsEatenTodayListBoxLabel = uilabel(app.Panel_2);
            app.FoodsEatenTodayListBoxLabel.Text = 'Foods Eaten Today';
            app.FoodsEatenTodayListBox = uilistbox(app.Panel_2);
            app.AddFoodButton = uibutton(app.Panel_2, 'push');
            app.AddFoodButton.Text = 'Add Food';
            app.RemoveFoodButton = uibutton(app.Panel_2, 'push');
            app.RemoveFoodButton.Text = 'Remove Food';
            app.ResetButton = uibutton(app.Panel_2, 'push');
            app.ResetButton.Text = 'Reset';
            app.WorkoutSummaryTab = uitab(app.TabGroup);
            app.WorkoutSummaryTab.Title = 'Workout Summary';
            app.Panel = uipanel(app.WorkoutSummaryTab);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.TotalStepsGraph = uiaxes(app.Panel);
            title(app.TotalStepsGraph, 'Comparing Total Steps')
            xlabel(app.TotalStepsGraph, 'Steps')
            zlabel(app.TotalStepsGraph, 'Z')
            app.EffortGraph = uiaxes(app.Panel);
            title(app.EffortGraph, 'Analyzing Workout Efforts')
            xlabel(app.EffortGraph, 'Effort Level')
            zlabel(app.EffortGraph, 'Z')
            app.ActivityBreakdownGraph = uiaxes(app.Panel);
            title(app.ActivityBreakdownGraph, 'Activity Breakdown')
            zlabel(app.ActivityBreakdownGraph, 'Z')
            app.SelectWorkoutButton = uibutton(app.Panel, 'push');
            app.SelectWorkoutButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWorkoutButtonPushed, true);
            app.SelectWorkoutButton.Text = 'Select Workout';
            app.FoodTrackerButton_3 = uibutton(app.Panel, 'push');
            app.FoodTrackerButton_3.ButtonPushedFcn = createCallbackFcn(app, @FoodTrackerButtonPushed, true);
            app.FoodTrackerButton_3.Text = 'Food Tracker';
            app.DailyCalorieButton_3 = uibutton(app.Panel, 'push');
            app.DailyCalorieButton_3.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.DailyCalorieButton_3.Text = 'Daily Calorie';
            app.HomeButton_3 = uibutton(app.Panel, 'push');
            app.HomeButton_3.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton_3.Text = 'Home';
            app.WorkoutSummaryButton_3 = uibutton(app.Panel, 'push');
            app.WorkoutSummaryButton_3.ButtonPushedFcn = createCallbackFcn(app, @WorkoutSummaryButtonPushed, true);
            app.WorkoutSummaryButton_3.Text = 'Workout Summary';
            app.WorkoutSummaryLabel = uilabel(app.Panel);
            app.WorkoutSummaryLabel.Text = 'Workout Summary';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.ImportDataButton = uibutton(app.Tab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.FilenameEditFieldLabel = uilabel(app.Tab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.Tab, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FitnessTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end